classdef controller_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        WaypointControlPanel            matlab.ui.container.Panel
        xEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        AddButton                       matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        WaypointEditFieldLabel          matlab.ui.control.Label
        WaypointEditField               matlab.ui.control.NumericEditField
        ClearAllButton                  matlab.ui.control.Button
        SaveWaypointsButton             matlab.ui.control.Button
        LoadWaypointsButton             matlab.ui.control.Button
        Atleast3waypointsrequiredLabel  matlab.ui.control.Label
        GeneratePathButton              matlab.ui.control.Button
        CommandIntervalsecEditFieldLabel  matlab.ui.control.Label
        CommandIntervalsecEditField     matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        SaveCommandsButton              matlab.ui.control.Button
        LoadCommandsButton              matlab.ui.control.Button
        DesiredLinearVelocitymsEditFieldLabel  matlab.ui.control.Label
        DesiredLinearVelocitymsEditField  matlab.ui.control.NumericEditField
        LookAheadDistancemEditFieldLabel  matlab.ui.control.Label
        LookAheadDistancemEditField     matlab.ui.control.NumericEditField
        ROSPublishControlButtonGroup    matlab.ui.container.ButtonGroup
        PauseButton                     matlab.ui.control.ToggleButton
        StartButton                     matlab.ui.control.ToggleButton
        ResetButton                     matlab.ui.control.ToggleButton
        ROSConnectButton                matlab.ui.control.Button
        masterIPEditFieldLabel          matlab.ui.control.Label
        masterIPEditField               matlab.ui.control.EditField
        ToggleLivePlotCheckBox          matlab.ui.control.CheckBox
        ToggleLivePPCheckBox            matlab.ui.control.CheckBox
        TogglePlotOdomCheckBox          matlab.ui.control.CheckBox
        myIPEditFieldLabel              matlab.ui.control.Label
        myIPEditField                   matlab.ui.control.EditField
    end

    
    properties (Access = public)
        waypoints=[0,0] % Array to hold the x,y coords of all waypoints in the form [x y; x2 y2; ... xn yn];
        defaultLoadDirectory='Saved_Waypoints\' % Directory to defaul load waypoints from
        saveDirectory='';
        purePursuitController=robotics.PurePursuit; % Default purePursuitController object
        linearVelocityCommands=[]; % List of linear velocity commands to be applied every x seconds
        angularVelocityCommands=[]; % List of angular veloicty command to be applied ever x seconds
        commandInterval=0.1; % Duration of commands
        predictedPath=[];
        actualPath=[];
        rosTimer=[];  %timer resonsible for changing ros message commands
        plotTimer=[]; %timer responsible for plotting when ros commands are being executed
        rosPubTimer=[]; %timer responsible for publishing ros commands
        rosMsg=[]; 
        velPub=[];
        odomSub=[];
        ROS_ITERATION=1; % iterator used to iterate through commands via timer
        
        livePlotFlag=0; % flag used to control if resources should be used for plotting while running
        livePurePursuitFlag=0;
        plotOdomFlag=0;
    end
    
    methods (Access = public)
        
        function plotter(app)
            % Function used to plot the waypoints
            plot(app.UIAxes,app.waypoints(:,1),app.waypoints(:,2),'b--o');
            hold(app.UIAxes,'on')
            for ii = 1:size(app.waypoints,1)
                % Label the points with the corresponding 'x' value
                text(app.UIAxes,app.waypoints(ii,1),app.waypoints(ii,2)-.5,num2str(ii));
            end
            hold(app.UIAxes','off')
        end
        
        function vel_msg_changer(app,obj,event)
            % Callback function used in accosiation with timer for:
            % 1. changing the velocity commands via app.ROS_ITERATION
            % 2. checking if all commands are used
            % 3. stopping the timers once commands are done
            
            
            %Get message from odom
            msg=receive(app.odomSub);
            x=msg.Pose.Pose.Position.X;
            y=msg.Pose.Pose.Position.Y;
            quat=[msg.Pose.Pose.Orientation.W msg.Pose.Pose.Orientation.X msg.Pose.Pose.Orientation.Y msg.Pose.Pose.Orientation.Z] ;
            [theta]=quat2eul(quat);
            robotLocation=[x,y,theta(1)];
            app.actualPath=[app.actualPath; robotLocation];
            if(app.livePurePursuitFlag)
                
                goalPoint=app.waypoints(end,:);
                goalRadius=.025; % Stopping point within final radius of end waypoint
                
                distanceToGoal=norm(robotLocation(1:2)-goalPoint);
                if(distanceToGoal>goalRadius)
                    [v,w]=app.purePursuitController(robotLocation);
                    app.linearVelocityCommands(app.ROS_ITERATION)=v;
                    app.angularVelocityCommands(app.ROS_ITERATION)=w;
                    app.predictedPath=[app.predictedPath; robotLocation(1:2)];
                    app.ROS_ITERATION=app.ROS_ITERATION+1;
                    
                    app.rosMsg.Linear.X=v;
                    app.rosMsg.Angular.Z=w;
                else
                    stop(app.rosPubTimer);
                    stop(app.rosTimer);
                    
                    % Make sure the turtlebot stops
                    app.rosMsg.Linear.X=0;
                    app.rosMsg.Angular.Z=0;
                    send(app.velPub,app.rosMsg);
                end
                
                
            else
                app.ROS_ITERATION=app.ROS_ITERATION+1;
                app.rosMsg.Linear.X=app.linearVelocityCommands(app.ROS_ITERATION);
                app.rosMsg.Angular.Z=app.angularVelocityCommands(app.ROS_ITERATION);
                if(app.ROS_ITERATION==length(app.linearVelocityCommands))
                    stop(app.rosPubTimer);
                    stop(app.rosTimer);
                    
                    % Make sure the turtlebot stops
                    app.rosMsg.Linear.X=0;
                    app.rosMsg.Angular.Z=0;
                    send(app.velPub,app.rosMsg);
                end
            end
            
        end
        
        function timer_callback_sparseplot(app,obj,event)
            % Callback function used with timer to plot the turtlebots
            % motion as it actually moves, used in association with timer
            % to minimize resource ussage
            if(app.livePurePursuitFlag)
                plotter(app);
                hold(app.UIAxes,'on')
                if(~isempty(app.predictedPath))
                    plot(app.UIAxes,app.predictedPath(:,1),app.predictedPath(:,2),'r-');
                    scatter(app.UIAxes,app.predictedPath(end,1),app.predictedPath(end,2),'r');
                    hold(app.UIAxes,'off')
                end
            else
                if(app.ROS_ITERATION>size(app.predictedPath,1))
                    stop(app.plotTimer);
                else
                    plotter(app);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.predictedPath(:,1),app.predictedPath(:,2),'r-');
                    if(app.plotOdomFlag)
                        plot(app.UIAxes,app.actualPath(:,1),app.actualPath(:,2),'r--');
                    end
                    scatter(app.UIAxes,app.predictedPath(app.ROS_ITERATION,1),app.predictedPath(app.ROS_ITERATION,2),'r');
                    hold(app.UIAxes,'off')
                   
                end
            end
            
        end
        
        function rosSend(app,obj,event)
            % Simple timercallback for sending messages based on
            % ros_iteration
            send(app.velPub,app.rosMsg);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            x=app.xEditField.Value;
            y=app.yEditField.Value;
            app.waypoints=[app.waypoints;x y];
            plotter(app);
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            waypointIdx=app.WaypointEditField.Value;
            app.waypoints(waypointIdx,:)=[];
            plotter(app);
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.waypoints=[0 0];
            plotter(app);
        end

        % Button pushed function: SaveWaypointsButton
        function SaveWaypointsButtonPushed(app, event)
            % Prompt the user to save the waypoints to a .mat file
            waypoints=app.waypoints;
            [file,path] = uiputfile('.mat');
            if(file~=0)
                save(strcat(path,file),'waypoints');
            end
        end

        % Button pushed function: LoadWaypointsButton
        function LoadWaypointsButtonPushed(app, event)
            % Prompt the user to load a set of waypoints from a .mat file
            [file,path]=uigetfile;
            if(file~=0)
                load(strcat(path,file));
                app.waypoints=waypoints;
                plotter(app);
            end
        end

        % Callback function
        function SelectfolderButtonPushed(app, event)
            app.saveDirectory=uigetdir;
            app.default_path_text_2.Text=app.saveDirectory;
        end

        % Button pushed function: GeneratePathButton
        function GeneratePathButtonPushed(app, event)
            
            if(size(app.waypoints,1)>2)
                app.commandInterval=app.CommandIntervalsecEditField.Value;
                app.purePursuitController.DesiredLinearVelocity=app.DesiredLinearVelocitymsEditField.Value;
                app.purePursuitController.LookaheadDistance=app.LookAheadDistancemEditField.Value;
                app.linearVelocityCommands=[]; % Reset lists
                app.angularVelocityCommands=[];
                app.predictedPath=[];
                
                robotLocation=[0 0 0]; %x, y, theta
                goalPoint=app.waypoints(end,:);
                goalRadius=.025; % Stopping point within final radius of end waypoint
                distanceToGoal=norm(robotLocation(1:2)-goalPoint);
                app.purePursuitController.Waypoints=app.waypoints;
                tic;
                while((distanceToGoal>goalRadius)&&(toc<10)) % Keep predicting robot motion path till goal is reached (OR TIME ELAPSED)
                    [v,w]=app.purePursuitController(robotLocation);
                    app.linearVelocityCommands=[app.linearVelocityCommands v];
                    app.angularVelocityCommands=[app.angularVelocityCommands w];
                    u=[v w];
                    robotLocation=motionModel(u,app.commandInterval,robotLocation);
                    distanceToGoal=norm(robotLocation(1:2)-goalPoint);
                    
                    app.predictedPath=[app.predictedPath;robotLocation];
                end
                if(toc>10)
                    % Processing time elapsed, no suitable path found.
                    app.Label.Text="Processing time elapsed, no suitable path found";
                    app.Label.FontColor=[1 0 0];
                else
                    app.Label.Text="Path succesfully generated";
                    app.Label.FontColor=[0 0 0];
                end
                
                
                plotter(app);
                hold(app.UIAxes,'on')
                plot(app.UIAxes,app.predictedPath(:,1),app.predictedPath(:,2),'r-');
                hold(app.UIAxes,'off')
            else
                app.Label.Text="No waypoints, path not generated";
                app.Label.FontColor=[1 0 0];
            end
            
        end

        % Button pushed function: SaveCommandsButton
        function SaveCommandsButtonPushed(app, event)
            % Prompt the user to save the commands to a .mat file
            linearVelocityCommands=app.linearVelocityCommands; % List of linear velocity commands to be applied every x seconds
            angularVelocityCommands=app.angularVelocityCommands; % List of angular veloicty command to be applied ever x seconds
            commandInterval=app.commandInterval; % Duration of commands
            predictedPath=app.predictedPath;
            [file,path] = uiputfile('.mat');
            if(file~=0)
                save(strcat(path,file),'linearVelocityCommands','angularVelocityCommands','commandInterval','predictedPath');
            end
        end

        % Button pushed function: LoadCommandsButton
        function LoadCommandsButtonPushed(app, event)
            %load commands (assumes waypoints exist...)
            if(size(app.waypoints,1)>2)
                [file,path]=uigetfile;
                if(file~=0)
                    load(strcat(path,file));
                    app.linearVelocityCommands=linearVelocityCommands; % List of linear velocity commands to be applied every x seconds
                    app.angularVelocityCommands=angularVelocityCommands; % List of angular veloicty command to be applied ever x seconds
                    app.commandInterval=commandInterval; % Duration of commands
                    app.predictedPath=predictedPath;
                    plotter(app);
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.predictedPath(:,1),app.predictedPath(:,2),'r-');
                    hold(app.UIAxes,'off')
                    app.Label.Text="Commands loaded";
                    app.Label.FontColor=[0 0 0];
                end
            else
                app.Label.Text="No waypoints found, commands not loaded";
                app.Label.FontColor=[1 0 0];
            end
        end

        % Selection changed function: ROSPublishControlButtonGroup
        function ROSPublishControlButtonGroupSelectionChanged(app, event)
        % This function is responsible for starting and stopping timers
        % associated with sending commands to turtlebots
            selectedButton = app.ROSPublishControlButtonGroup.SelectedObject;
            if(selectedButton.Text=="Pause")
                stop(app.rosPubTimer);
                stop(app.plotTimer);
                stop(app.rosTimer);
            elseif(selectedButton.Text=="Start")
                
                %Check if path was generated offline, or using
                %lviePurePursuit
                
                if(isempty(app.velPub))
                    app.Label.Text="Error: Possible no path or ROS connection detected";
                    app.Label.FontColor=[1 0 0];
                else
                    % Timer for publishing velocity messages
                    app.rosTimer=timer('ExecutionMode','fixedRate','Period',app.commandInterval,'Name','rosPubTimer');
                    app.rosTimer.StartDelay=1+app.commandInterval;
                    app.rosTimer.TimerFcn={@app.vel_msg_changer};
          
                    % One shot timer (~thread) to handle which velocity
                    % message should be published at which time
                    app.rosPubTimer=timer('ExecutionMode','fixedRate','Period',0.05,'Name','rosTimer');
                    app.rosPubTimer.StartDelay=1.0;
                    app.rosPubTimer.TimerFcn={@app.rosSend};
                    
              
                    %Time to handle plotting at a sparse rate
                    app.plotTimer=timer('ExecutionMode','fixedSpacing','Name','plotTimer','Period',0.5);
                    app.plotTimer.StartDelay=1;
                    app.plotTimer.TimerFcn={@app.timer_callback_sparseplot};
                    
                    %Quickly reset odom
                    odomResetPub=rospublisher('/mobile_base/commands/reset_odometry');
                    msg=rosmessage(odomResetPub);
                    send(odomResetPub,msg);
                    pause(1);
                    
                    app.rosMsg.Linear.X=0;
                    app.rosMsg.Angular.Z=0;
                    start(app.rosPubTimer);
                    start(app.rosTimer);
                    if(app.livePlotFlag)
                        start(app.plotTimer);
                    end
                    
                    if(app.livePurePursuitFlag)
                        app.predictedPath=[];
                        app.linearVelocityCommands=[];
                        app.angularVelocityCommands=[];
                        app.purePursuitController.DesiredLinearVelocity=app.DesiredLinearVelocitymsEditField.Value;
                        app.purePursuitController.LookaheadDistance=app.LookAheadDistancemEditField.Value;
                        app.purePursuitController.Waypoints=app.waypoints;
                    end
                end
            elseif(selectedButton.Text=="Reset")
                stop(app.rosPubTimer);
                stop(app.plotTimer);
                stop(app.rosPubTimer);
                delete(app.rosTimer);
                delete(app.plotTimer);
                delete(app.rosPubTimer);
                app.ROS_ITERATION=1;
            end
        end

        % Button pushed function: ROSConnectButton
        function ROSConnectButtonPushed(app, event)
            rosshutdown
            try
                setenv('ROS_MASTER_URI',strcat('http://',strcat(app.masterIPEditField.Value,':11311')));
                setenv('ROS_IP',app.myIPEditField.Value');
                rosinit(app.masterIPEditField.Value);
                app.Label.Text="Connected to over ROS";
                app.Label.FontColor=[0 0 0];
                app.velPub=rospublisher('/mobile_base/commands/velocity');
                app.rosMsg=rosmessage(app.velPub);
                app.odomSub=rossubscriber('/odom');
            catch ME
                app.Label.Text="Error, ROS connection not found over IP";
                app.Label.FontColor=[1 0 0];
            end
            
        end

        % Value changed function: ToggleLivePlotCheckBox
        function ToggleLivePlotCheckBoxValueChanged(app, event)
            value = app.ToggleLivePlotCheckBox.Value;
            app.livePlotFlag=value;
        end

        % Value changed function: ToggleLivePPCheckBox
        function ToggleLivePPCheckBoxValueChanged(app, event)
            value = app.ToggleLivePPCheckBox.Value;
            app.livePurePursuitFlag=value;
        end

        % Value changed function: TogglePlotOdomCheckBox
        function TogglePlotOdomCheckBoxValueChanged(app, event)
            value = app.TogglePlotOdomCheckBox.Value;
            app.plotOdomFlag=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Path')
            xlabel(app.UIAxes, 'x(m)')
            ylabel(app.UIAxes, 'y(m)')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.XTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.UIAxes.XTickLabel = {'-10'; '-8'; '-6'; '-4'; '-2'; '0'; '2'; '4'; '6'; '8'; '10'};
            app.UIAxes.YTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.UIAxes.YTickLabel = {'-10'; '-8'; '-6'; '-4'; '-2'; '0'; '2'; '4'; '6'; '8'; '10'};
            app.WaypointControlPanel = uipanel(app.UIFigure);
            app.WaypointControlPanel.Title = 'Waypoint Control';
            app.xEditFieldLabel = uilabel(app.WaypointControlPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.WaypointControlPanel, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.WaypointControlPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.WaypointControlPanel, 'numeric');
            app.AddButton = uibutton(app.WaypointControlPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.WaypointControlPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.WaypointEditFieldLabel = uilabel(app.WaypointControlPanel);
            app.WaypointEditFieldLabel.Text = 'Waypoint #';
            app.WaypointEditField = uieditfield(app.WaypointControlPanel, 'numeric');
            app.ClearAllButton = uibutton(app.WaypointControlPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.SaveWaypointsButton = uibutton(app.WaypointControlPanel, 'push');
            app.SaveWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWaypointsButtonPushed, true);
            app.SaveWaypointsButton.Text = 'Save Waypoints';
            app.LoadWaypointsButton = uibutton(app.WaypointControlPanel, 'push');
            app.LoadWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWaypointsButtonPushed, true);
            app.LoadWaypointsButton.Text = 'Load Waypoints';
            app.Atleast3waypointsrequiredLabel = uilabel(app.WaypointControlPanel);
            app.Atleast3waypointsrequiredLabel.Text = '(At least 3 waypoints required)';
            app.GeneratePathButton = uibutton(app.UIFigure, 'push');
            app.GeneratePathButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePathButtonPushed, true);
            app.GeneratePathButton.Text = 'Generate Path';
            app.CommandIntervalsecEditFieldLabel = uilabel(app.UIFigure);
            app.CommandIntervalsecEditFieldLabel.Text = 'Command Interval (sec)';
            app.CommandIntervalsecEditField = uieditfield(app.UIFigure, 'numeric');
            app.CommandIntervalsecEditField.Value = 0.1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SaveCommandsButton = uibutton(app.UIFigure, 'push');
            app.SaveCommandsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCommandsButtonPushed, true);
            app.SaveCommandsButton.Text = 'Save Commands';
            app.LoadCommandsButton = uibutton(app.UIFigure, 'push');
            app.LoadCommandsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCommandsButtonPushed, true);
            app.LoadCommandsButton.Text = 'Load Commands';
            app.DesiredLinearVelocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredLinearVelocitymsEditFieldLabel.Text = 'Desired Linear Velocity (m/s)';
            app.DesiredLinearVelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredLinearVelocitymsEditField.Value = 0.1;
            app.LookAheadDistancemEditFieldLabel = uilabel(app.UIFigure);
            app.LookAheadDistancemEditFieldLabel.Text = 'Look Ahead Distance(m)';
            app.LookAheadDistancemEditField = uieditfield(app.UIFigure, 'numeric');
            app.LookAheadDistancemEditField.Value = 1;
            app.ROSPublishControlButtonGroup = uibuttongroup(app.UIFigure);
            app.ROSPublishControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ROSPublishControlButtonGroupSelectionChanged, true);
            app.ROSPublishControlButtonGroup.Title = 'R.O.S. Publish Control';
            app.PauseButton = uitogglebutton(app.ROSPublishControlButtonGroup);
            app.PauseButton.Text = 'Pause';
            app.PauseButton.Value = true;
            app.StartButton = uitogglebutton(app.ROSPublishControlButtonGroup);
            app.StartButton.Text = 'Start';
            app.ResetButton = uitogglebutton(app.ROSPublishControlButtonGroup);
            app.ResetButton.Text = 'Reset';
            app.ROSConnectButton = uibutton(app.UIFigure, 'push');
            app.ROSConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ROSConnectButtonPushed, true);
            app.ROSConnectButton.Text = 'R.O.S. Connect';
            app.masterIPEditFieldLabel = uilabel(app.UIFigure);
            app.masterIPEditFieldLabel.Text = 'masterIP';
            app.masterIPEditField = uieditfield(app.UIFigure, 'text');
            app.masterIPEditField.Value = '192.168.1.1';
            app.ToggleLivePlotCheckBox = uicheckbox(app.UIFigure);
            app.ToggleLivePlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToggleLivePlotCheckBoxValueChanged, true);
            app.ToggleLivePlotCheckBox.Text = 'Toggle Live Plot';
            app.ToggleLivePPCheckBox = uicheckbox(app.UIFigure);
            app.ToggleLivePPCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToggleLivePPCheckBoxValueChanged, true);
            app.ToggleLivePPCheckBox.Text = 'Toggle Live PP';
            app.TogglePlotOdomCheckBox = uicheckbox(app.UIFigure);
            app.TogglePlotOdomCheckBox.ValueChangedFcn = createCallbackFcn(app, @TogglePlotOdomCheckBoxValueChanged, true);
            app.TogglePlotOdomCheckBox.Text = 'Toggle Plot Odom';
            app.myIPEditFieldLabel = uilabel(app.UIFigure);
            app.myIPEditFieldLabel.Text = 'myIP';
            app.myIPEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controller_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end